classdef RNN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        NumberofNetstoTestEditFieldLabel  matlab.ui.control.Label
        NumNets                         matlab.ui.control.NumericEditField
        NeuralNetworkCreatorLabel       matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        SetButton                       matlab.ui.control.Button
        OtherParametersLabel            matlab.ui.control.Label
        ThresholdSteepnessEditFieldLabel  matlab.ui.control.Label
        ThresholdSteepness              matlab.ui.control.NumericEditField
        LearningRateEditFieldLabel      matlab.ui.control.Label
        LearningRate                    matlab.ui.control.NumericEditField
        RunButton                       matlab.ui.control.Button
        ExperimentalDataFileEditFieldLabel  matlab.ui.control.Label
        ExperimentalData                matlab.ui.control.EditField
        TrainingDataFileEditFieldLabel  matlab.ui.control.Label
        TrainingData                    matlab.ui.control.EditField
    end

    methods (Access = private)

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            % Update the number of rows of the table
            n = app.NumNets.Value;
            iterations = zeros(n,1);
            memLength = zeros(n,1);
            hiddenLayers = zeros(n,1);
            units = zeros(n,1);
            app.UITable.Data = [iterations memLength hiddenLayers units];
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            iterations = app.UITable.Data(:,1);
            memLength = app.UITable.Data(:,2);
            hiddenLayers = app.UITable.Data(:,3);
            units = app.UITable.Data(:,4);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            Main_AppDesigner
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.NumberofNetstoTestEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofNetstoTestEditFieldLabel.Text = 'Number of Nets to Test';
            app.NumNets = uieditfield(app.UIFigure, 'numeric');
            app.NumNets.Limits = [0 Inf];
            app.NeuralNetworkCreatorLabel = uilabel(app.UIFigure);
            app.NeuralNetworkCreatorLabel.Text = 'Neural Network Creator';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Iterations/Sample'; 'Memory Length'; 'Hidden Layers'; 'Units/Hidden Layer'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.SetButton = uibutton(app.UIFigure, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.OtherParametersLabel = uilabel(app.UIFigure);
            app.OtherParametersLabel.Text = 'Other Parameters';
            app.ThresholdSteepnessEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdSteepnessEditFieldLabel.Text = 'Threshold Steepness';
            app.ThresholdSteepness = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdSteepness.Limits = [0 Inf];
            app.ThresholdSteepness.Value = 1;
            app.LearningRateEditFieldLabel = uilabel(app.UIFigure);
            app.LearningRateEditFieldLabel.Text = 'Learning Rate';
            app.LearningRate = uieditfield(app.UIFigure, 'numeric');
            app.LearningRate.Limits = [0 Inf];
            app.LearningRate.Value = 0.05;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ExperimentalDataFileEditFieldLabel = uilabel(app.UIFigure);
            app.ExperimentalDataFileEditFieldLabel.Text = 'Experimental Data File';
            app.ExperimentalData = uieditfield(app.UIFigure, 'text');
            app.ExperimentalData.Value = '.txt';
            app.TrainingDataFileEditFieldLabel = uilabel(app.UIFigure);
            app.TrainingDataFileEditFieldLabel.Text = 'Training Data File';
            app.TrainingData = uieditfield(app.UIFigure, 'text');
            app.TrainingData.Value = '.txt';
        end
    end

    methods (Access = public)

        % Construct app
        function app = RNN

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end